classdef FireDistribution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FireDPSCalculatorUIFigure     matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        SpellPowerGearEditFieldLabel  matlab.ui.control.Label
        SpellPowerGearEditField       matlab.ui.control.NumericEditField
        BonusHitGearLabel             matlab.ui.control.Label
        BonusHitGearEditField         matlab.ui.control.NumericEditField
        CastsperfightEditFieldLabel   matlab.ui.control.Label
        CastsperfightEditField        matlab.ui.control.NumericEditField
        BonusCritGearLabel            matlab.ui.control.Label
        BonusCritGearEditField        matlab.ui.control.NumericEditField
        NumberofMagesEditFieldLabel   matlab.ui.control.Label
        NumberofMagesEditField        matlab.ui.control.NumericEditField
        HeadBuffButton                matlab.ui.control.CheckBox
        HeartBuffButton               matlab.ui.control.CheckBox
        DragonSlayerLabel             matlab.ui.control.Label
        HeartofHakkarLabel            matlab.ui.control.Label
        SFBuffButton                  matlab.ui.control.CheckBox
        SongflowerLabel               matlab.ui.control.Label
        DireMaulButton                matlab.ui.control.CheckBox
        DireMaulLabel                 matlab.ui.control.Label
        FlaskBuffButton               matlab.ui.control.CheckBox
        FlaskofSupremePowerLabel      matlab.ui.control.Label
        GenerateButton                matlab.ui.control.Button
        ComputeStatWeightsCheckBox    matlab.ui.control.CheckBox
        ComputeDPSMetricsCheckBox     matlab.ui.control.CheckBox
        OutputOptionsLabel            matlab.ui.control.Label
        InputOptionsLabel             matlab.ui.control.Label
        IntellectBaseGearEditFieldLabel  matlab.ui.control.Label
        IntellectBaseGearEditField    matlab.ui.control.NumericEditField
        LoadNameEditFieldLabel        matlab.ui.control.Label
        LoadNameEditField             matlab.ui.control.EditField
        CreateButton                  matlab.ui.control.Button
        DeleteButton                  matlab.ui.control.Button
        InvalidNameLabel              matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        Image                         matlab.ui.control.Image
        CritLabel                     matlab.ui.control.Label
        HitEditField                  matlab.ui.control.NumericEditField
        HitEditField_2Label           matlab.ui.control.Label
        SPEditField                   matlab.ui.control.NumericEditField
        SPEditFieldLabel              matlab.ui.control.Label
        DPSEditField                  matlab.ui.control.NumericEditField
        ExpectedDPSEditFieldLabel     matlab.ui.control.Label
        ExpectedDPSEditField          matlab.ui.control.NumericEditField
        MaximumLikelihoodEditFieldLabel  matlab.ui.control.Label
        MaximumLikelihoodEditField    matlab.ui.control.NumericEditField
        CurrentLoadOutDisplay         matlab.ui.control.EditField
        DPSUIAxes                     matlab.ui.control.UIAxes
        ListBox                       matlab.ui.control.ListBox
        LoadoutsLabel                 matlab.ui.control.Label
        DPSLabel                      matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        loads;
        probLim;
        DPSLim;
        path;
    end
    
    
    methods (Access = private)
        
        
        function hideCreate(app)
            app.InvalidNameLabel.Visible = 'off';
            app.LoadNameEditField.Enable = 'off';
            app.LoadNameEditFieldLabel.Enable = 'off';
            app.CreateButton.Enable = 'off';
            app.GenerateButton.Enable = 'on';
            app.NumberofMagesEditField.Enable = 'on';
            app.CastsperfightEditField.Enable = 'on';
            app.IntellectBaseGearEditField.Enable = 'on';
            app.SpellPowerGearEditField.Enable = 'on';
            app.BonusHitGearEditField.Enable = 'on';
            app.BonusCritGearEditField.Enable = 'on';
            app.DireMaulButton.Enable = 'on';
            app.SFBuffButton.Enable = 'on';
            app.HeadBuffButton.Enable = 'on';
            app.HeartBuffButton.Enable = 'on';
            app.FlaskBuffButton.Enable = 'on';
            app.ComputeDPSMetricsCheckBox.Enable = 'on';
            app.ComputeStatWeightsCheckBox.Enable= 'on';
            app.OutputOptionsLabel.Enable = 'on';
            app.DeleteButton.Enable = 'on';
        end
        function showCreate(app)
            app.LoadNameEditField.Enable = 'on';
            app.LoadNameEditFieldLabel.Enable = 'on';
            app.CreateButton.Enable = 'on';
            app.GenerateButton.Enable = 'off';
            app.NumberofMagesEditField.Enable = 'off';
            app.CastsperfightEditField.Enable = 'off';
            app.IntellectBaseGearEditField.Enable = 'off';
            app.SpellPowerGearEditField.Enable = 'off';
            app.BonusHitGearEditField.Enable = 'off';
            app.BonusCritGearEditField.Enable = 'off';
            app.DireMaulButton.Enable = 'off';
            app.SFBuffButton.Enable = 'off';
            app.HeadBuffButton.Enable = 'off';
            app.HeartBuffButton.Enable = 'off';
            app.FlaskBuffButton.Enable = 'off';
            app.ComputeDPSMetricsCheckBox.Enable = 'off';
            app.ComputeStatWeightsCheckBox.Enable= 'off';
            app.OutputOptionsLabel.Enable = 'off';
            app.DeleteButton.Enable = 'off';
        end
        
        function DPSenabled(app)
            app.MaximumLikelihoodEditField.Enable ='on';
            app.MaximumLikelihoodEditFieldLabel.Enable ='on';
            app.ExpectedDPSEditField.Enable = 'on';
            app.ExpectedDPSEditFieldLabel.Enable = 'on';
        end
        function DPSdisabled(app)
            app.MaximumLikelihoodEditField.Enable ='off';
            app.MaximumLikelihoodEditFieldLabel.Enable ='off';
            app.ExpectedDPSEditField.Enable = 'off';
            app.ExpectedDPSEditFieldLabel.Enable = 'off';
        end
        
        function weightsEnabled(app)
            app.HitEditField.Enable ='on';
            app.SPEditField.Enable = 'on';
            app.DPSEditField.Enable = 'on';
            app.HitEditField_2Label.Enable ='on';
            app.SPEditFieldLabel.Enable = 'on';
            app.CritLabel.Enable = 'on';
            app.DPSLabel.Enable = 'on';
        end
        function weightsDisabled(app)
            app.HitEditField.Enable ='off';
            app.SPEditField.Enable = 'off';
            app.DPSEditField.Enable = 'off';
            app.HitEditField_2Label.Enable ='off';
            app.CritLabel.Enable = 'off';
            app.SPEditFieldLabel.Enable ='off';
            app.DPSLabel.Enable = 'off';
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [pathstr,name]=fileparts(which('loadout.mat'));
            load(name,'loadout')
            app.path = pathstr;
            app.loads = loadout;
            app.ListBox.Items = {app.loads{:,1},'New'};
            app.CurrentLoadOutDisplay.Value = app.ListBox.Items{1};
            app.DPSLim = max(cellfun(@max,loadout(:,15)));
            app.probLim = max(cellfun(@max,loadout(2:end,16)));
            app.ListBox.Value = app.ListBox.Items{1};
            ListBoxValueChanged(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.FireDPSCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {297, 297};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {412, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            loadName = app.ListBox.Value;
            loadnum = find(strcmp(app.loads(:,1),loadName));
            
            nmages = app.NumberofMagesEditField.Value;
            ncasts = app.CastsperfightEditField.Value;
            int = app.IntellectBaseGearEditField.Value;
            SPgear = app.SpellPowerGearEditField.Value;
            hit = app.BonusHitGearEditField.Value;
            critGear = app.BonusCritGearEditField.Value;
            DMT = app.DireMaulButton.Value;
            SF = app.SFBuffButton.Value;
            HEAD = app.HeadBuffButton.Value;
            HEART = app.HeartBuffButton.Value;
            FLASK = app.FlaskBuffButton.Value;
            computeDPS = app.ComputeDPSMetricsCheckBox.Value == 1;
            computestatWeights = app.ComputeStatWeightsCheckBox.Value== 1;
            computeDPSBox = app.ComputeDPSMetricsCheckBox.Value;
            computestatWeightsBox = app.ComputeStatWeightsCheckBox.Value;
            
            cla(app.DPSUIAxes)
            app.ExpectedDPSEditField.Value = 0;
            app.MaximumLikelihoodEditField.Value = 0;
            app.HitEditField.Value = 0;
            app.SPEditField.Value = 0;
            app.DPSEditField.Value = 0;
            app.GenerateButton.Text = 'Running...';
            pause(0.00000001);
            
            
            [DPS,totalprobs,hitW, SPW,DPSW]= fireDPS(nmages,ncasts, int, SPgear,hit, critGear, DMT, SF, HEAD, HEART, FLASK, computeDPS,computestatWeights);
            
            loaddata = {loadName,nmages,ncasts,int,SPgear,hit,critGear,DMT,SF,HEAD,HEART,FLASK,computeDPSBox,computestatWeightsBox,DPS,totalprobs,DPSW,SPW,hitW};
            app.loads(loadnum,:) = loaddata;
            app.DPSLim = max([app.DPSLim,DPS]);
            app.probLim = max([app.probLim, max(totalprobs(2:end))]);
            app.GenerateButton.Text = 'Execute';
            app.GenerateButton.Enable = 'on';
            ListBoxValueChanged(app)
            pause(0.00000001);
            loadout = app.loads;
            save(fullfile(app.path, 'loadout.mat'), 'loadout');
%             save('loadout.mat','loadout')
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.LoadNameEditField.Value = "";
            value = app.ListBox.Value;
            if value ~= "New"
                hideCreate(app)
                app.CurrentLoadOutDisplay.Value = value;
                loadnum = find(strcmp({app.loads{:,1}},value));
                app.NumberofMagesEditField.Value = app.loads{loadnum, 2};
                app.CastsperfightEditField.Value = app.loads{loadnum, 3};
                app.IntellectBaseGearEditField.Value = app.loads{loadnum, 4};
                app.SpellPowerGearEditField.Value = app.loads{loadnum, 5};
                app.BonusHitGearEditField.Value = app.loads{loadnum, 6};
                app.BonusCritGearEditField.Value = app.loads{loadnum, 7};
                app.DireMaulButton.Value = app.loads{loadnum, 8};
                app.SFBuffButton.Value = app.loads{loadnum, 9};
                app.HeadBuffButton.Value = app.loads{loadnum, 10};
                app.HeartBuffButton.Value = app.loads{loadnum, 11};
                app.FlaskBuffButton.Value = app.loads{loadnum, 12};
               
                app.ComputeDPSMetricsCheckBox.Value = app.loads{loadnum, 13};
                app.ComputeStatWeightsCheckBox.Value = app.loads{loadnum, 14};
                DPS = app.loads{loadnum, 15};
                totalprobs = app.loads{loadnum, 16};
                DPSW = app.loads{loadnum, 17};
                SPW = app.loads{loadnum, 18};
                hitW = app.loads{loadnum, 19};
                
                computeDPS = app.ComputeDPSMetricsCheckBox.Value == 1 && max(size(DPS))>1;
                computestatWeights = app.ComputeStatWeightsCheckBox.Value == 1 && (DPSW +SPW +hitW)>0;
                if computeDPS
                    DPSenabled(app)
                    plot(app.DPSUIAxes,DPS,totalprobs)
                    ylim(app.DPSUIAxes,[0,app.probLim])
                    xlim(app.DPSUIAxes,[0,app.DPSLim])
                    app.ExpectedDPSEditField.Value = sum(DPS.*totalprobs');
                    [~,maxl] = max(totalprobs);
                    app.MaximumLikelihoodEditField.Value = DPS(maxl);
                else
                    DPSdisabled(app)
                    cla(app.DPSUIAxes)
                    app.ExpectedDPSEditField.Value = 0;
                    app.MaximumLikelihoodEditField.Value = 0;
                end
                if computestatWeights
                    weightsEnabled(app)
                    app.HitEditField.Value = hitW;
                    app.SPEditField.Value = SPW;
                    app.DPSEditField.Value = DPSW;
                else
                    weightsDisabled(app)
                    app.HitEditField.Value = 0;
                    app.SPEditField.Value = 0;
                    app.DPSEditField.Value = 0;
                end
                
            else
                cla(app.DPSUIAxes)
                app.CurrentLoadOutDisplay.Value = "";
                app.ExpectedDPSEditField.Value = 0;
                app.MaximumLikelihoodEditField.Value = 0;
                app.HitEditField.Value = 0;
                app.SPEditField.Value = 0;
                app.DPSEditField.Value = 0;
                showCreate(app)

            end
            
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            loadName = app.LoadNameEditField.Value;
            if ~ strcmp(loadName,'') && ~ strcmp(loadName,'New') && ~ strcmp(loadName,'new')
                nmages = app.NumberofMagesEditField.Value;
                ncasts = app.CastsperfightEditField.Value;
                int = app.IntellectBaseGearEditField.Value;
                SPgear = app.SpellPowerGearEditField.Value;
                hit = app.BonusHitGearEditField.Value;
                critGear = app.BonusCritGearEditField.Value;
                DMT = app.DireMaulButton.Value;
                SF = app.SFBuffButton.Value;
                HEAD = app.HeadBuffButton.Value;
                HEART = app.HeartBuffButton.Value;
                FLASK = app.FlaskBuffButton.Value;
                computeDPSBox = app.ComputeDPSMetricsCheckBox.Value;
                computestatWeightsBox = app.ComputeStatWeightsCheckBox.Value;
                DPS = 0;
                totalprobs = 0;
                DPSW = 0;
                SPW = 0;
                hitW = 0;
                newload = {loadName,nmages,ncasts,int,SPgear,hit,critGear,DMT,SF,HEAD,HEART,FLASK,computeDPSBox,computestatWeightsBox,DPS,totalprobs,DPSW,SPW,hitW};
                app.loads = vertcat(app.loads,newload);
                app.ListBox.Items = {app.loads{:,1},'New'};
                app.ListBox.Value = app.ListBox.Items{end-1};
                app.LoadNameEditField.Value = '';
                ListBoxValueChanged(app)
            else
                app.InvalidNameLabel.Visible = 'on';
            end
        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushed(app, event)
            value = app.ListBox.Value;
            app.CurrentLoadOutDisplay.Value = value;
            loadmask = strcmp({app.loads{:,1}},value);
            app.loads = app.loads(~loadmask,:);
            app.ListBox.Items = {app.loads{:,1},'New'};
            app.ListBox.Value = app.ListBox.Items{1};
            ListBoxValueChanged(app);
            loadout = app.loads;
%             save('loadout.mat','loadout')
            save(fullfile(app.path, 'loadout.mat'), 'loadout');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FireDPSCalculatorUIFigure = uifigure('Visible', 'off');
            app.FireDPSCalculatorUIFigure.Name = 'Fire Damage and Weights';
            app.FireDPSCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.FireDPSCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SpellPowerGearEditFieldLabel = uilabel(app.LeftPanel);
            app.SpellPowerGearEditFieldLabel.Text = 'Spell Power (Gear)';
            app.SpellPowerGearEditField = uieditfield(app.LeftPanel, 'numeric');
            app.SpellPowerGearEditField.Limits = [0 10000];
            app.SpellPowerGearEditField.ValueDisplayFormat = '%8.f';
            app.SpellPowerGearEditField.Value = 556;
            app.BonusHitGearLabel = uilabel(app.LeftPanel);
            app.BonusHitGearLabel.Text = 'Bonus Hit % (Gear)';
            app.BonusHitGearEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BonusHitGearEditField.Limits = [0 100];
            app.BonusHitGearEditField.ValueDisplayFormat = '%8.f';
            app.BonusHitGearEditField.Value = 6;
            app.CastsperfightEditFieldLabel = uilabel(app.LeftPanel);
            app.CastsperfightEditFieldLabel.Text = 'Casts per fight';
            app.CastsperfightEditField = uieditfield(app.LeftPanel, 'numeric');
            app.CastsperfightEditField.Limits = [1 50];
            app.CastsperfightEditField.ValueDisplayFormat = '%8.f';
            app.CastsperfightEditField.Value = 14;
            app.BonusCritGearLabel = uilabel(app.LeftPanel);
            app.BonusCritGearLabel.Text = 'Bonus Crit % (Gear)';
            app.BonusCritGearEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BonusCritGearEditField.Limits = [0 100];
            app.BonusCritGearEditField.ValueDisplayFormat = '%8.f';
            app.BonusCritGearEditField.Value = 7;
            app.NumberofMagesEditFieldLabel = uilabel(app.LeftPanel);
            app.NumberofMagesEditFieldLabel.Text = 'Number of Mages';
            app.NumberofMagesEditField = uieditfield(app.LeftPanel, 'numeric');
            app.NumberofMagesEditField.Limits = [1 20];
            app.NumberofMagesEditField.ValueDisplayFormat = '%8.f';
            app.NumberofMagesEditField.Value = 7;
            app.HeadBuffButton = uicheckbox(app.LeftPanel);
            app.HeadBuffButton.Text = '';
            app.HeadBuffButton.Value = true;
            app.HeartBuffButton = uicheckbox(app.LeftPanel);
            app.HeartBuffButton.Text = '';
            app.HeartBuffButton.Value = true;
            app.DragonSlayerLabel = uilabel(app.LeftPanel);
            app.DragonSlayerLabel.Text = 'Dragon Slayer';
            app.HeartofHakkarLabel = uilabel(app.LeftPanel);
            app.HeartofHakkarLabel.Text = 'Heart of Hakkar';
            app.SFBuffButton = uicheckbox(app.LeftPanel);
            app.SFBuffButton.Text = '';
            app.SFBuffButton.Value = true;
            app.SongflowerLabel = uilabel(app.LeftPanel);
            app.SongflowerLabel.Text = 'Songflower';
            app.DireMaulButton = uicheckbox(app.LeftPanel);
            app.DireMaulButton.Text = '';
            app.DireMaulButton.Value = true;
            app.DireMaulLabel = uilabel(app.LeftPanel);
            app.DireMaulLabel.Text = 'Dire Maul';
            app.FlaskBuffButton = uicheckbox(app.LeftPanel);
            app.FlaskBuffButton.Text = '';
            app.FlaskofSupremePowerLabel = uilabel(app.LeftPanel);
            app.FlaskofSupremePowerLabel.Text = 'Flask of Supreme Power';
            app.GenerateButton = uibutton(app.LeftPanel, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.ComputeStatWeightsCheckBox = uicheckbox(app.LeftPanel);
            app.ComputeStatWeightsCheckBox.Text = 'Compute Stat Weights';
            app.ComputeDPSMetricsCheckBox = uicheckbox(app.LeftPanel);
            app.ComputeDPSMetricsCheckBox.Visible = 'off';
            app.ComputeDPSMetricsCheckBox.Text = 'Compute DPS Metrics';
            app.ComputeDPSMetricsCheckBox.Value = true;
            app.OutputOptionsLabel = uilabel(app.LeftPanel);
            app.OutputOptionsLabel.Text = 'Output Options';
            app.InputOptionsLabel = uilabel(app.LeftPanel);
            app.InputOptionsLabel.Text = 'Input Options';
            app.IntellectBaseGearEditFieldLabel = uilabel(app.LeftPanel);
            app.IntellectBaseGearEditFieldLabel.Text = 'Intellect (Base/Gear)';
            app.IntellectBaseGearEditField = uieditfield(app.LeftPanel, 'numeric');
            app.IntellectBaseGearEditField.Limits = [0 1000];
            app.IntellectBaseGearEditField.ValueDisplayFormat = '%8.f';
            app.IntellectBaseGearEditField.Value = 298;
            app.LoadNameEditFieldLabel = uilabel(app.LeftPanel);
            app.LoadNameEditFieldLabel.Text = 'Load Name';
            app.LoadNameEditField = uieditfield(app.LeftPanel, 'text');
            app.CreateButton = uibutton(app.LeftPanel, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.DeleteButton = uibutton(app.LeftPanel, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushed, true);
            app.DeleteButton.Text = 'Delete';
            app.InvalidNameLabel = uilabel(app.LeftPanel);
            app.InvalidNameLabel.Visible = 'off';
            app.InvalidNameLabel.Text = 'Invalid Name';
            app.RightPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.RightPanel);
            app.Image.Enable = 'off';
            app.Image.ImageSource = 'logo-no-text-55268946bcc74bd7badaa57e461fe4fc.png';
            app.CritLabel = uilabel(app.RightPanel);
            app.CritLabel.Text = '1 % Crit = ';
            app.HitEditField = uieditfield(app.RightPanel, 'numeric');
            app.HitEditField.Editable = 'off';
            app.HitEditField_2Label = uilabel(app.RightPanel);
            app.HitEditField_2Label.Text = '% Hit  = ';
            app.SPEditField = uieditfield(app.RightPanel, 'numeric');
            app.SPEditField.Editable = 'off';
            app.SPEditFieldLabel = uilabel(app.RightPanel);
            app.SPEditFieldLabel.Text = 'SP =';
            app.DPSEditField = uieditfield(app.RightPanel, 'numeric');
            app.DPSEditField.Editable = 'off';
            app.ExpectedDPSEditFieldLabel = uilabel(app.RightPanel);
            app.ExpectedDPSEditFieldLabel.Text = 'Expected DPS';
            app.ExpectedDPSEditField = uieditfield(app.RightPanel, 'numeric');
            app.ExpectedDPSEditField.Editable = 'off';
            app.MaximumLikelihoodEditFieldLabel = uilabel(app.RightPanel);
            app.MaximumLikelihoodEditFieldLabel.Text = 'Maximum Likelihood';
            app.MaximumLikelihoodEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaximumLikelihoodEditField.Editable = 'off';
            app.CurrentLoadOutDisplay = uieditfield(app.RightPanel, 'text');
            app.CurrentLoadOutDisplay.Editable = 'off';
            app.CurrentLoadOutDisplay.Visible = 'off';
            app.CurrentLoadOutDisplay.Value = 'World Buffed';
            app.DPSUIAxes = uiaxes(app.RightPanel);
            title(app.DPSUIAxes, 'DPS Distribution Per Mage')
            xlabel(app.DPSUIAxes, 'DPS')
            ylabel(app.DPSUIAxes, 'Probability')
            app.ListBox = uilistbox(app.FireDPSCalculatorUIFigure);
            app.ListBox.Items = {'World Buffed', 'Unbuffed', 'New', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'World Buffed';
            app.LoadoutsLabel = uilabel(app.FireDPSCalculatorUIFigure);
            app.LoadoutsLabel.Text = 'Load outs';
            app.DPSLabel = uilabel(app.FireDPSCalculatorUIFigure);
            app.DPSLabel.Text = 'DPS';
            % Show the figure after all components are created
            app.FireDPSCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FireDistribution

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FireDPSCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FireDPSCalculatorUIFigure)
        end
    end
end